classdef app1 < matlab.apps.AppBase

    properties (Access = public)
        UIFigure matlab.ui.Figure
        UIAxes matlab.ui.control.UIAxes
        LoadMapButton matlab.ui.control.Button
        GenerateMapButton matlab.ui.control.Button
        DrawPathButton matlab.ui.control.Button
        SavePathButton matlab.ui.control.Button
        LoadPathButton matlab.ui.control.Button
        StatusLabel matlab.ui.control.Label
    end

    properties (Access = private)
        Map = []
        MapFolder = ""
        Path = []
        PathPlot = []
    end

    methods (Access = private)

        function updateStatus(app, message)
            app.StatusLabel.Text = "Status: " + string(message);
        end

        function repoRoot = getRepoRoot(app)
            appFile = which(class(app));
            repoRoot = fileparts(fileparts(appFile));
        end

        function mapGeneratorExe = getMapGeneratorExe(app)
            repoRoot = app.getRepoRoot();
            mapGeneratorExe = fullfile(repoRoot, "MapGenerator", "bin", "x64", "Release", "MapGenerator.exe");
        end

        function clearPathPlot(app)
            if ~isempty(app.PathPlot) && isgraphics(app.PathPlot)
                delete(app.PathPlot);
            end
            app.PathPlot = [];
        end

        function showPath(app, pathPoints)
            app.clearPathPlot();
            if isempty(pathPoints)
                return
            end

            hold(app.UIAxes, "on")
            app.PathPlot = plot(app.UIAxes, pathPoints(:,1), pathPoints(:,2), "r-o", "LineWidth", 2);
            hold(app.UIAxes, "off")
        end

        function loadMapIntoApp(app, jsonFile, mapFolder)
            [map, xmin, xmax, ymin, ymax] = readMap(jsonFile);
            displayMap(app.UIAxes, map, xmin, xmax, ymin, ymax)
            app.Map = map;
            app.MapFolder = string(mapFolder);
            app.Path = [];
            app.clearPathPlot();
            app.updateStatus("Loaded map");
        end

        function LoadMapButtonPushed(app, ~)
            startFolder = fullfile(app.getRepoRoot(), "Maps");
            [file, path] = uigetfile(fullfile(startFolder, "*.json"), "Select map");
            if isequal(file, 0)
                app.updateStatus("Load map cancelled");
                return
            end

            app.loadMapIntoApp(fullfile(path, file), path);
        end

        function GenerateMapButtonPushed(app, ~)
            exe = app.getMapGeneratorExe();
            if ~isfile(exe)
                uialert(app.UIFigure, "MapGenerator.exe was not found.", "Missing executable");
                app.updateStatus("Map generator not found");
                return
            end

            app.updateStatus("Generating map...");
            [status, output] = system(['"' char(exe) '"']);
            if status ~= 0
                uialert(app.UIFigure, string(strtrim(output)), "Map generation failed");
                app.updateStatus("Map generation failed");
                return
            end

            mapsRoot = fullfile(app.getRepoRoot(), "Maps");
            maps = dir(mapsRoot);
            maps = maps([maps.isdir]);
            maps = maps(~ismember({maps.name}, {".", ".."}));
            if isempty(maps)
                uialert(app.UIFigure, "No generated maps were found in the Maps folder.", "No maps");
                app.updateStatus("No generated maps found");
                return
            end

            [~, idx] = max([maps.datenum]);
            folder = fullfile(mapsRoot, maps(idx).name);
            jsonFile = fullfile(folder, "map.json");
            if ~isfile(jsonFile)
                uialert(app.UIFigure, "Generated map.json was not found.", "Missing map");
                app.updateStatus("Generated map file missing");
                return
            end

            app.loadMapIntoApp(jsonFile, folder);
        end

        function DrawPathButtonPushed(app, ~)
            if isempty(app.Map)
                uialert(app.UIFigure, "Load or generate a map before drawing a path.", "No map");
                return
            end

            app.clearPathPlot();
            app.Path = [];
            title(app.UIAxes, "Click points and double-click to finish")
            roi = drawpolyline(app.UIAxes, "Color", "r", "LineWidth", 2);
            wait(roi);
            if ~isvalid(roi)
                title(app.UIAxes, "Map")
                app.updateStatus("Path drawing cancelled");
                return
            end

            app.Path = roi.Position;
            delete(roi);
            title(app.UIAxes, "Map")
            app.showPath(app.Path);
            app.updateStatus("Path updated");
        end

        function SavePathButtonPushed(app, ~)
            if isempty(app.Path)
                uialert(app.UIFigure, "No path to save.", "Missing path");
                return
            end

            if strlength(app.MapFolder) == 0
                uialert(app.UIFigure, "No map folder is available for saving the path.", "Missing map folder");
                return
            end

            savePath(char(app.MapFolder), app.Path)
            app.updateStatus("Path saved");
        end

        function LoadPathButtonPushed(app, ~)
            if isempty(app.Map)
                uialert(app.UIFigure, "Load or generate a map before loading a path.", "No map");
                return
            end

            [file, path] = uigetfile("*.json", "Select path");
            if isequal(file, 0)
                app.updateStatus("Load path cancelled");
                return
            end

            app.Path = loadPath(fullfile(path, file));
            app.showPath(app.Path);
            app.updateStatus("Path loaded");
        end

        function createComponents(app)
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            app.UIFigure = uifigure("Visible", "off");
            app.UIFigure.Name = "Map Tool";
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, "Map")
            xlabel(app.UIAxes, "X")
            ylabel(app.UIAxes, "Y")
            colormap(app.UIAxes, "hot");
            app.LoadMapButton = uibutton(app.UIFigure, "push");
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = "Load Map";
            app.GenerateMapButton = uibutton(app.UIFigure, "push");
            app.GenerateMapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMapButtonPushed, true);
            app.GenerateMapButton.Text = "Generate Map";
            app.DrawPathButton = uibutton(app.UIFigure, "push");
            app.DrawPathButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPathButtonPushed, true);
            app.DrawPathButton.Text = "Draw Path";
            app.SavePathButton = uibutton(app.UIFigure, "push");
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = "Save Path";
            app.LoadPathButton = uibutton(app.UIFigure, "push");
            app.LoadPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPathButtonPushed, true);
            app.LoadPathButton.Text = "Load Path";
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = "Status: Ready";
            app.UIFigure.Visible = "on";
        end
    end

    methods (Access = public)

        function app = app1
            createComponents(app)
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        function delete(app)
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                delete(app.UIFigure)
            end
        end
    end
end